classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LoadandMatchImageButton  matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        QueryLabel               matlab.ui.control.Label
        MatchLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000 1000];
        end

        % Button pushed function: LoadandMatchImageButton
        function LoadandMatchImageButtonPushed(app, event)
              [file,path]=uigetfile({'*.bmp'}, 'Select file');
              filename = fullfile(path, file);
              [im, map] = imread(filename);
              actualIm = ind2rgb(im, map);      
              app.Image.ImageSource = actualIm;

              matching_filename = match_image(filename);
              [im, map] = imread(matching_filename);
              actualIm = ind2rgb(im, map);      
              app.Image2.ImageSource = actualIm;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadandMatchImageButton = uibutton(app.UIFigure, 'push');
            app.LoadandMatchImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandMatchImageButtonPushed, true);
            app.LoadandMatchImageButton.Text = 'Load and Match Image';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.QueryLabel = uilabel(app.UIFigure);
            app.QueryLabel.Text = 'Query';
            app.MatchLabel = uilabel(app.UIFigure);
            app.MatchLabel.Text = 'Match';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end